classdef app_layout < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SaveMenu            matlab.ui.container.Menu
        CajonMenu           matlab.ui.container.Menu
        TaishogotoMenu      matlab.ui.container.Menu
        LoadMenu            matlab.ui.container.Menu
        GridLayout          matlab.ui.container.GridLayout
        GridLayout2_3       matlab.ui.container.GridLayout
        PlayKotoButton      matlab.ui.control.Button
        GridLayout2_2       matlab.ui.container.GridLayout
        PlayCajonButton     matlab.ui.control.Button
        GridLayout2_4       matlab.ui.container.GridLayout
        RecordButton        matlab.ui.control.Button
        PlayRecordedButton  matlab.ui.control.Button
        GridLayout5         matlab.ui.container.GridLayout
        RecordingLabel      matlab.ui.control.Label
        kotoAudioFT         matlab.ui.control.UIAxes
        kotoAxes            matlab.ui.control.UIAxes
        cajonAxes           matlab.ui.control.UIAxes
        ogAudioAxes         matlab.ui.control.UIAxes
        cajonAudioFT        matlab.ui.control.UIAxes
        ogAudioFT           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ogAudioVec; % Original Audio
        cajonAudioVec; % Cajon Audio
        kotoAudioVec; % Koto Audio
        Fs = 44100; %Sampling Frequency
        N = 4; % Filter Order
        recObj;
    end
    
    methods (Access = public)
        
        function filtered = filterCajon(app)
            [b, a] = butter(app.N, [50 400]/(app.Fs/2), "bandpass");
            filtered = filter(b, a, app.ogAudioVec);
        end
        
        function filtered = filterKoto(app)
            [b, a] = butter(app.N, [500 2000]/(app.Fs/2), "bandpass");
            filtered = filter(b, a, app.ogAudioVec);
        end
        
        function update(app)
            plot(app.ogAudioAxes, app.ogAudioVec, 'b-');
            ogFT = fft(app.ogAudioVec);
            plot(app.ogAudioFT, abs(ogFT(1:20000)), 'b-');
            yLims = app.ogAudioAxes.YLim;
            yFTMaxLim = app.ogAudioFT.YLim(2);

            app.cajonAudioVec = app.filterCajon();
            plot(app.cajonAxes, app.cajonAudioVec, 'g-');
            app.cajonAxes.YLim = [yLims(1) yLims(2)];
            cajonFT = fft(app.cajonAudioVec);
            plot(app.cajonAudioFT, abs(cajonFT(1:20000)), 'g-');
            app.cajonAudioFT.YLim = [0 yFTMaxLim];

            app.kotoAudioVec = app.filterKoto();
            plot(app.kotoAxes, app.kotoAudioVec, "-");
            app.kotoAxes.YLim = [yLims(1) yLims(2)];
            kotoFT = fft(app.kotoAudioVec);
            plot(app.kotoAudioFT, abs(kotoFT(1:20000)), 'g-');
            % app.kotoAudioFT.YLim = [0 yFTMaxLim];

            h1 = app.kotoAxes.Children;
            line1 = h1(1);
            line1.Color = "#ff9900";

            h2 = app.kotoAudioFT.Children;
            line2 = h2(1);
            line2.Color = "#ff9900";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            if app.RecordButton.Text == "Record"
                app.RecordButton.Text = "Stop";
                app.PlayRecordedButton.Enable = "off";
                app.PlayCajonButton.Enable = "off";
                app.PlayKotoButton.Enable = "off";

                clear sound;

                % app.Fs = 48000;
                audioDevice = 1;
                app.recObj = audiorecorder(app.Fs, 16, 1, audioDevice);
                record(app.recObj);
                app.RecordingLabel.Visible = "on";
            elseif app.RecordButton.Text == "Stop"
                app.RecordButton.Text = "Record";
                app.PlayRecordedButton.Enable = "on";
                app.PlayCajonButton.Enable = "on";
                app.PlayKotoButton.Enable = "on";

                stop(app.recObj);
                app.ogAudioVec = getaudiodata(app.recObj);
                app.update();
                app.RecordingLabel.Visible = "off";
            end
        end

        % Button pushed function: PlayRecordedButton
        function PlayRecordedButtonPushed(app, event)
            if app.PlayRecordedButton.Text == "Play"
                app.PlayRecordedButton.Text = "Stop";
                app.PlayCajonButton.Text = "Play";
                app.PlayKotoButton.Text = "Play";

                clear sound;
                sound(app.ogAudioVec, app.Fs);
            elseif app.PlayRecordedButton.Text == "Stop"
                app.PlayRecordedButton.Text = "Play";

                clear sound;
            end
        end

        % Button pushed function: PlayCajonButton
        function PlayCajonButtonPushed(app, event)
            if app.PlayCajonButton.Text == "Play"
                app.PlayCajonButton.Text = "Stop";
                app.PlayRecordedButton.Text = "Play";
                app.PlayKotoButton.Text = "Play";

                clear sound;
                sound(app.cajonAudioVec, app.Fs);
            elseif app.PlayCajonButton.Text == "Stop"
                app.PlayCajonButton.Text = "Play";

                clear sound;
            end
        end

        % Button pushed function: PlayKotoButton
        function PlayKotoButtonPushed(app, event)
            if app.PlayKotoButton.Text == "Play"
                app.PlayKotoButton.Text = "Stop";
                app.PlayRecordedButton.Text = "Play";
                app.PlayCajonButton.Text = "Play";

                clear sound;
                sound(app.kotoAudioVec, app.Fs);
            elseif app.PlayKotoButton.Text == "Stop"
                app.PlayKotoButton.Text = "Play";
                clear sound;
            end
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            [file, path] = uigetfile('*.wav');
            [app.ogAudioVec, app.Fs] = audioread(append(path, file));
            figure(app.UIFigure);
            app.update();
        end

        % Menu selected function: CajonMenu
        function CajonMenuSelected(app, event)
            [file, path] = uiputfile('*.wav',"Save Cajon Audio", "cajon_output.wav");
            audiowrite(append(path, file), app.cajonAudioVec, app.Fs);
        end

        % Menu selected function: TaishogotoMenu
        function TaishogotoMenuSelected(app, event)
            [file, path] = uiputfile('*.wav', "Save Taishogoto Audio", "koto_output.wav");
            audiowrite(append(path, file), app.kotoAudioVec, app.Fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SaveMenu = uimenu(app.UIFigure);
            app.SaveMenu.Text = 'Save';
            app.CajonMenu = uimenu(app.SaveMenu);
            app.CajonMenu.MenuSelectedFcn = createCallbackFcn(app, @CajonMenuSelected, true);
            app.CajonMenu.Text = 'Cajon';
            app.TaishogotoMenu = uimenu(app.SaveMenu);
            app.TaishogotoMenu.MenuSelectedFcn = createCallbackFcn(app, @TaishogotoMenuSelected, true);
            app.TaishogotoMenu.Text = 'Taishogoto';
            app.LoadMenu = uimenu(app.UIFigure);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ogAudioFT = uiaxes(app.GridLayout);
            title(app.ogAudioFT, 'Frequency Domain')
            xlabel(app.ogAudioFT, 'Frequency (Hz)')
            ylabel(app.ogAudioFT, 'Magnitude')
            zlabel(app.ogAudioFT, 'Z')
            app.cajonAudioFT = uiaxes(app.GridLayout);
            title(app.cajonAudioFT, 'Frequency Domain')
            xlabel(app.cajonAudioFT, 'Frequency (Hz)')
            ylabel(app.cajonAudioFT, 'Magnitude')
            zlabel(app.cajonAudioFT, 'Z')
            app.ogAudioAxes = uiaxes(app.GridLayout);
            title(app.ogAudioAxes, 'Original Audio')
            xlabel(app.ogAudioAxes, 'time (s)')
            ylabel(app.ogAudioAxes, 'Amplitude')
            zlabel(app.ogAudioAxes, 'Z')
            app.cajonAxes = uiaxes(app.GridLayout);
            title(app.cajonAxes, 'Cajon Beatbox 50Hz - 500Hz')
            xlabel(app.cajonAxes, 'time (s)')
            ylabel(app.cajonAxes, 'Amplitude')
            zlabel(app.cajonAxes, 'Z')
            app.kotoAxes = uiaxes(app.GridLayout);
            title(app.kotoAxes, 'Taishogoto(Koto guitar) 500Hz - 2kHz')
            xlabel(app.kotoAxes, 'time (s)')
            ylabel(app.kotoAxes, 'Amplitude')
            zlabel(app.kotoAxes, 'Z')
            app.kotoAudioFT = uiaxes(app.GridLayout);
            title(app.kotoAudioFT, 'Frequency Domain')
            xlabel(app.kotoAudioFT, 'Frequency (Hz)')
            ylabel(app.kotoAudioFT, 'Magnitude')
            zlabel(app.kotoAudioFT, 'Z')
            app.GridLayout2_4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout2_4);
            app.RecordingLabel = uilabel(app.GridLayout5);
            app.RecordingLabel.Visible = 'off';
            app.RecordingLabel.Text = 'Recording...';
            app.PlayRecordedButton = uibutton(app.GridLayout2_4, 'push');
            app.PlayRecordedButton.ButtonPushedFcn = createCallbackFcn(app, @PlayRecordedButtonPushed, true);
            app.PlayRecordedButton.Text = 'Play';
            app.RecordButton = uibutton(app.GridLayout2_4, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.GridLayout2_2 = uigridlayout(app.GridLayout);
            app.PlayCajonButton = uibutton(app.GridLayout2_2, 'push');
            app.PlayCajonButton.ButtonPushedFcn = createCallbackFcn(app, @PlayCajonButtonPushed, true);
            app.PlayCajonButton.Text = 'Play';
            app.GridLayout2_3 = uigridlayout(app.GridLayout);
            app.PlayKotoButton = uibutton(app.GridLayout2_3, 'push');
            app.PlayKotoButton.ButtonPushedFcn = createCallbackFcn(app, @PlayKotoButtonPushed, true);
            app.PlayKotoButton.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_layout

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end